classdef BALLIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CarbonScoreCalculatorLabel     matlab.ui.control.Label
        CarbonScoretonnesEditField_2   matlab.ui.control.NumericEditField
        CarbonScoretonnesEditField_2Label  matlab.ui.control.Label
        HowmanyhoursperyeardoyouspendonaplaneEditField  matlab.ui.control.NumericEditField
        HowmanyhoursperyeardoyouspendonaplaneEditFieldLabel  matlab.ui.control.Label
        CalculateCarbonScoreButton     matlab.ui.control.Button
        WhattypeofmeatdoyouprimarilyeatDropDown  matlab.ui.control.DropDown
        WhattypeofmeatdoyouprimarilyeatDropDownLabel  matlab.ui.control.Label
        HowmanytimesaweekdoyoueatmeatSpinner  matlab.ui.control.Spinner
        HowmanytimesaweekdoyoueatmeatSpinnerLabel  matlab.ui.control.Label
        WhatpercentofyourelectricitygridusesfossilfuelsSlider  matlab.ui.control.Slider
        WhatpercentofyourelectricitygridusesfossilfuelsLabel  matlab.ui.control.Label
        WhattypeofcardoyouuseDropDown  matlab.ui.control.DropDown
        WhattypeofcardoyouuseDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        grid_fossil = 0;
        elec_emissions = 785 * 12 * 490e-6 % world-nuclear.org/information-library/energy-and-the-environment/carbon-dioxide-emissions-from-electricity | https://www.oeb.ca/sites/default/files/uploads/Report_Defining_Typical_Elec_Customer_20160414.pdf
        car_type = 5.2; % https://climate.mit.edu/ask-mit/are-electric-vehicles-definitely-better-climate-gas-powered-cars
        meat_usage = 0;
        meat_type = 0.003; % https://css.umich.edu/publications/factsheets/sustainability-indicators/carbon-footprint-factsheet
        plane_usage = 0;
        baseline = 3
        carbon_score = 0;
        car_score = 0;
        elec_score = 0;
        meat_score = 0;
        plane_score = 0;
    end
    
    methods (Access = private)
        
        function calc_score(app)
            app.car_score = app.car_type;
            app.elec_score = app.grid_fossil * app.elec_emissions;
            app.meat_score = app.meat_usage * app.meat_type;
            app.plane_score = app.plane_usage;
            app.carbon_score = app.baseline + app.car_score + app.meat_score + app.plane_score;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % WhatpercentofyourelectricitygridusesfossilfuelsSlider
        function WhatpercentofyourelectricitygridusesfossilfuelsSliderValueChanged(app, event)
            app.grid_fossil = app.WhatpercentofyourelectricitygridusesfossilfuelsSlider.Value;
        end

        % Callback function
        function HowmanytimesaweekdoyouuseacarSpinnerValueChanged(app, event)
            app.car_usage = app.HowmanytimesaweekdoyouuseacarSpinner.Value;
        end

        % Value changed function: WhattypeofcardoyouuseDropDown
        function WhattypeofcardoyouuseDropDownValueChanged(app, event)
            value = app.WhattypeofcardoyouuseDropDown.Value;
            switch value
                case "None"
                    app.car_type = 0;
                case "Electric"
                    app.car_type = 1.7;
                case "Plug-In Hybrid"
                    app.car_type = 2.6;
                case "Hybrid"
                    app.car_type = 2.8;
                case "Gas"
                    app.car_type = 5.2;
            end
        end

        % Value changed function: HowmanytimesaweekdoyoueatmeatSpinner
        function HowmanytimesaweekdoyoueatmeatSpinnerValueChanged(app, event)
            app.meat_usage = app.HowmanytimesaweekdoyoueatmeatSpinner.Value;
        end

        % Value changed function: WhattypeofmeatdoyouprimarilyeatDropDown
        function WhattypeofmeatdoyouprimarilyeatDropDownValueChanged(app, event)
            value = app.WhattypeofmeatdoyouprimarilyeatDropDown.Value;
            switch value
                case "None"
                    app.meat_type = 0;
                case "Beef"
                    app.meat_type = 0.003;
                case "Chicken"
                    app.meat_type = 5.7e-4;
                case "Other"
                    app.meat_type = 7.8e-4;
            end
        end

        % Value changed function: 
        % HowmanyhoursperyeardoyouspendonaplaneEditField
        function HowmanyhoursperyeardoyouspendonaplaneEditFieldValueChanged(app, event)
            app.plane_usage = app.HowmanyhoursperyeardoyouspendonaplaneEditField.Value * 0.25;
        end

        % Button pushed function: CalculateCarbonScoreButton
        function CalculateCarbonScoreButtonPushed(app, event)
            app.calc_score();
            app.CarbonScoretonnesEditField_2.Value = app.carbon_score;
            balloons_car = int32(app.car_score * (534/65));
            balloons_elec = int32((app.elec_score + app.baseline) * (534/65));
            balloons_meat = int32(app.meat_score * (534/65));
            balloons_plane = int32(app.plane_score * (534/65));
            additional_data = [balloons_car, balloons_elec, balloons_meat, balloons_plane];
            numb_balls = sum(additional_data);
            new_data = [additional_data, numb_balls];
            function centers = bouncing_spheres_3d(num_spheres)
               % tic;
                % Define the radius of the spheres
                radius = 250;
            
                % Create a sphere
                % [X, Y, Z] = sphere;
                % 
                % % Scale the sphere
                % X = radius * X;
                % Y = radius * Y; 
                % Z = radius * Z;
            
                % Generate random initial centers for the spheres
                % centers = 2*length * (rand(num_spheres, 3) - 0.5);
                
                 % Calculate the number of layers needed for the pyramid
                layers = 0;
                total_spheres = 0;
                dim = 0;
                curr_dim = 1;
                while total_spheres < num_spheres
                    total_spheres = total_spheres + curr_dim^2;
                    layers = layers + 1;
                    dim = dim + 1;
                    if dim == curr_dim
                        curr_dim = curr_dim + 1;
                        dim = 0;
                    end
                end 
                % disp(total_spheres);
                % disp(num_spheres);
            
                % Initialize centers for the spheres
                centers = zeros(total_spheres, 3);
                % centers = [];
            
                % Generate centers for the spheres in a pyramid-like arrangement
                sphere_count = 0;
                height = 0;
                for l = 0:layers-1
                for k = 0:l
                    for i = 0:l
                        for j = 0:l
                            if sphere_count >= total_spheres 
                                break;
                            end
                            % if ~((i == 0) || (i == l) || (j == 0) || (j == l))
                            %     sphere_count = sphere_count + 1;
                            %     continue;
                            % end
                            x = (i - l/2) * 2 * radius;
                            y = (j - l/2) * 2 * radius;
                            z = (layers-height) * 2 * radius; % Place each layer above the previous one
                            centers(sphere_count+1, 1) = x + 10 * rand();
                            centers(sphere_count+1, 2) = y - 8000 + 10 * rand();
                            centers(sphere_count+1, 3) = z + 10 * rand();
                            sphere_count = sphere_count + 1;
                        end
                        if sphere_count >= total_spheres 
                            break;
                        end
                    end
                    height = height + 1;
                end 
                end
            
                return;
                % Create a figure and plot the spheres
                % figure;
                % hold on;
                % h = gobjects(total_spheres, 1);
                % for i = 1:total_spheres
                %     h(i) = surf(X + centers(i, 1), Y + centers(i, 2), Z + centers(i, 3));
                %     set(h(i), 'EdgeColor', 'none');
                % end
                % hold off;
                % 
                % % Set axis properties
                % axis equal;
                % grid on;
                % xlabel('X');
                % ylabel('Y');
                % zlabel('Z');
                % title('Bouncing Spheres');
                % view(3); % Set a 3D view
            
                % Fix the axis limits to ensure a stable view
                % axis([-100 100 -100 100 -layers*radius - 3*radius, radius]);
                % tot_time = toc;
                % disp(tot_time);
            end
            
            % Call the function with the desired number of spheres
            coords = bouncing_spheres_3d(numb_balls); % Adjust the number of spheres as needed

            disp(coords)
            
            % Save the coordinates to a text file with additional data in the first line
            fileID = fopen('balloon_centers.txt', 'w');
            fprintf(fileID, '%d %d %d %d %d\n', new_data);
            fclose(fileID);
            save('balloon_centers.txt', 'coords', '-ascii', '-append');
            
            % Define the paths with escaped backslashes and ensure paths with spaces are handled
            blenderPath = strtrim('C:\\Program Files\\Blender Foundation\\Blender 4.2\\blender.exe');
            scriptPath = strtrim('C:\\Users\\mehak\\Downloads\\96-uploads_files_2720101_textures-2\\BallBlender.py');

            
            % Check if Blender executable exists
            if ~isfile(blenderPath)
                error('Blender executable not found at %s', blenderPath);
            end
            
            % Check if the Python script exists
            if ~isfile(scriptPath)
                error('Python script not found at %s', scriptPath);
            end
            
            % Create the command string using sprintf and enclosing paths in double quotes
            command = sprintf('"%s" --background --python "%s"', blenderPath, scriptPath);
            
            % Display the command for verification
            disp(['Running command: ', command]);
            
            % Execute the system command and handle errors
            try
                [status, cmdout] = system(command);
                
                % Display the full output from the command
                disp('Command output:');
                disp(cmdout);
                
                % Check the status and display appropriate messages
                if status == 0
                    disp('Blender script executed successfully.');
                else
                    error('Error executing Blender script: %s', cmdout);
                end
            catch ME
                disp('An error occurred while executing the Blender script:');
                disp(ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhattypeofcardoyouuseDropDownLabel = uilabel(app.UIFigure);
            app.WhattypeofcardoyouuseDropDownLabel.Text = 'What type of car do you use?';
            app.WhattypeofcardoyouuseDropDown = uidropdown(app.UIFigure);
            app.WhattypeofcardoyouuseDropDown.Items = {'None', 'Electric', 'Plug-In Hybrid', 'Hybrid', 'Gas'};
            app.WhattypeofcardoyouuseDropDown.ValueChangedFcn = createCallbackFcn(app, @WhattypeofcardoyouuseDropDownValueChanged, true);
            app.WhattypeofcardoyouuseDropDown.Value = 'Gas';
            app.WhatpercentofyourelectricitygridusesfossilfuelsLabel = uilabel(app.UIFigure);
            app.WhatpercentofyourelectricitygridusesfossilfuelsLabel.Text = 'What percent of your electricity grid uses fossil fuels?';
            app.WhatpercentofyourelectricitygridusesfossilfuelsSlider = uislider(app.UIFigure);
            app.WhatpercentofyourelectricitygridusesfossilfuelsSlider.ValueChangedFcn = createCallbackFcn(app, @WhatpercentofyourelectricitygridusesfossilfuelsSliderValueChanged, true);
            app.HowmanytimesaweekdoyoueatmeatSpinnerLabel = uilabel(app.UIFigure);
            app.HowmanytimesaweekdoyoueatmeatSpinnerLabel.Text = 'How many times a week do you eat meat?';
            app.HowmanytimesaweekdoyoueatmeatSpinner = uispinner(app.UIFigure);
            app.HowmanytimesaweekdoyoueatmeatSpinner.ValueChangedFcn = createCallbackFcn(app, @HowmanytimesaweekdoyoueatmeatSpinnerValueChanged, true);
            app.WhattypeofmeatdoyouprimarilyeatDropDownLabel = uilabel(app.UIFigure);
            app.WhattypeofmeatdoyouprimarilyeatDropDownLabel.Text = 'What type of meat do you primarily eat?';
            app.WhattypeofmeatdoyouprimarilyeatDropDown = uidropdown(app.UIFigure);
            app.WhattypeofmeatdoyouprimarilyeatDropDown.Items = {'Beef', 'Chicken', 'Other', 'None'};
            app.WhattypeofmeatdoyouprimarilyeatDropDown.ValueChangedFcn = createCallbackFcn(app, @WhattypeofmeatdoyouprimarilyeatDropDownValueChanged, true);
            app.WhattypeofmeatdoyouprimarilyeatDropDown.Value = 'Beef';
            app.CalculateCarbonScoreButton = uibutton(app.UIFigure, 'push');
            app.CalculateCarbonScoreButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCarbonScoreButtonPushed, true);
            app.CalculateCarbonScoreButton.Text = 'Calculate Carbon Score!';
            app.HowmanyhoursperyeardoyouspendonaplaneEditFieldLabel = uilabel(app.UIFigure);
            app.HowmanyhoursperyeardoyouspendonaplaneEditFieldLabel.Text = 'How many hours per year do you spend on a plane?';
            app.HowmanyhoursperyeardoyouspendonaplaneEditField = uieditfield(app.UIFigure, 'numeric');
            app.HowmanyhoursperyeardoyouspendonaplaneEditField.ValueChangedFcn = createCallbackFcn(app, @HowmanyhoursperyeardoyouspendonaplaneEditFieldValueChanged, true);
            app.CarbonScoretonnesEditField_2Label = uilabel(app.UIFigure);
            app.CarbonScoretonnesEditField_2Label.Text = 'Carbon Score [tonnes]:';
            app.CarbonScoretonnesEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CarbonScoreCalculatorLabel = uilabel(app.UIFigure);
            app.CarbonScoreCalculatorLabel.Text = 'Carbon Score Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BALLIN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end